classdef app_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        COMMANDSLabel            matlab.ui.control.Label
        LEDPanel_2               matlab.ui.container.Panel
        BuzzerSwitch             matlab.ui.control.ToggleSwitch
        StateLampLabel           matlab.ui.control.Label
        StateLamp                matlab.ui.control.Lamp
        SoundPanel               matlab.ui.container.Panel
        LEDSwitch                matlab.ui.control.ToggleSwitch
        StateLamp_2Label         matlab.ui.control.Label
        StateLED                 matlab.ui.control.Lamp
        CONNECTIONLabel          matlab.ui.control.Label
        StateLamp_3Label         matlab.ui.control.Label
        connLed                  matlab.ui.control.Lamp
        connSw                   matlab.ui.control.Switch
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        REALTIMEINDICATORSLabel  matlab.ui.control.Label
        SamplingSwitchLabel      matlab.ui.control.Label
        SamplingSwitch           matlab.ui.control.Switch
        UNALAFarukLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        s % Description
        tempArray % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            comName = seriallist;            
            app.s = serial(comName);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {454, 454};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {271, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: BuzzerSwitch
        function BuzzerSwitchValueChanged(app, event)
            value = app.BuzzerSwitch.Value;
            if value=="On"
                fprintf(app.s,'%s','1');
            else
                fprintf(app.s,'%s','2');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(instrfind);
            delete(app)
            
        end

        % Value changed function: connSw
        function connSwValueChanged(app, event)
            value = app.connSw.Value;
            if value=="On"
                fopen(app.s);
            else
                fclose(app.s);
            end
            if app.s.Status == "open"
                app.connLed.Color="green";
            else
                app.connLed.Color="red";
            end
        end

        % Value changed function: LEDSwitch
        function LEDSwitchValueChanged(app, event)
            value = app.LEDSwitch.Value;
            if value=="On"
                fprintf(app.s,'%s','3');
            else
                fprintf(app.s,'%s','4');
            end
        end

        % Value changed function: SamplingSwitch
        function SamplingSwitchValueChanged(app, event)
            value = app.SamplingSwitch.Value;
            if value=="On"
                flushinput(app.s);
                fprintf(app.s,'%s','9');
                while value=="On" 
                    data=fscanf(app.s);
                    if data(1)=='D'
                        app.tempArray=[app.tempArray str2double(data(2:6))];
                    end                    
                    disp(data);
                    pause(0.4);
                    if(app.SamplingSwitch.Value=="Off")
                        flushinput(app.s);
                        break;
                    end
                    plot(app.UIAxes,app.tempArray);
                end
            else
                flushinput(app.s);
                fprintf(app.s,'%s','q');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.COMMANDSLabel = uilabel(app.LeftPanel);
            app.COMMANDSLabel.Text = 'COMMANDS';
            app.LEDPanel_2 = uipanel(app.LeftPanel);
            app.LEDPanel_2.Title = 'LED';
            app.BuzzerSwitch = uiswitch(app.LEDPanel_2, 'toggle');
            app.BuzzerSwitch.ValueChangedFcn = createCallbackFcn(app, @BuzzerSwitchValueChanged, true);
            app.StateLampLabel = uilabel(app.LEDPanel_2);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.LEDPanel_2);
            app.SoundPanel = uipanel(app.LeftPanel);
            app.SoundPanel.Title = 'Sound';
            app.LEDSwitch = uiswitch(app.SoundPanel, 'toggle');
            app.LEDSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDSwitchValueChanged, true);
            app.StateLamp_2Label = uilabel(app.SoundPanel);
            app.StateLamp_2Label.Text = 'State';
            app.StateLED = uilamp(app.SoundPanel);
            app.CONNECTIONLabel = uilabel(app.LeftPanel);
            app.CONNECTIONLabel.Text = 'CONNECTION';
            app.StateLamp_3Label = uilabel(app.LeftPanel);
            app.StateLamp_3Label.Text = 'State';
            app.connLed = uilamp(app.LeftPanel);
            app.connSw = uiswitch(app.LeftPanel, 'slider');
            app.connSw.ValueChangedFcn = createCallbackFcn(app, @connSwValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Temperature')
            xlabel(app.UIAxes, 'Nth Sample')
            ylabel(app.UIAxes, 'Celcius')
            app.UIAxes.YTick = [15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35];
            app.UIAxes.YTickLabel = {'15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'};
            app.REALTIMEINDICATORSLabel = uilabel(app.RightPanel);
            app.REALTIMEINDICATORSLabel.Text = 'REAL TIME INDICATORS';
            app.SamplingSwitchLabel = uilabel(app.RightPanel);
            app.SamplingSwitchLabel.Text = 'Sampling';
            app.SamplingSwitch = uiswitch(app.RightPanel, 'slider');
            app.SamplingSwitch.ValueChangedFcn = createCallbackFcn(app, @SamplingSwitchValueChanged, true);
            app.UNALAFarukLabel = uilabel(app.RightPanel);
            app.UNALAFarukLabel.Text = 'UNAL, A. Faruk';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end